classdef VigenereGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimpleVigenereUIFigure  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        KeyLabel                matlab.ui.control.Label
        KeyField                matlab.ui.control.EditField
        CryptographictextLabel  matlab.ui.control.Label
        CryptographictextArea   matlab.ui.control.TextArea
        PlainTextField          matlab.ui.control.EditField
        PlainTextLabel          matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changing function: PlainTextField
        function PlainTextFieldValueChanging(app, event)
            changingValue = lower(event.Value);
            if (length(changingValue) > length(app.KeyField.Value) && ~isempty(app.KeyField.Value))
                app.CryptographictextArea.Value = encryptLowerCaseText(changingValue,app.KeyField.Value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimpleVigenereUIFigure = uifigure('Visible', 'off');
            app.SimpleVigenereUIFigure.Name = 'Simple Vigenere';
            app.SimpleVigenereUIFigure.Icon = 'icon.png';
            app.GridLayout = uigridlayout(app.SimpleVigenereUIFigure);
            app.PlainTextLabel = uilabel(app.GridLayout);
            app.PlainTextLabel.Text = 'Plain Text';
            app.PlainTextField = uieditfield(app.GridLayout, 'text');
            app.PlainTextField.ValueChangingFcn = createCallbackFcn(app, @PlainTextFieldValueChanging, true);
            app.CryptographictextArea = uitextarea(app.GridLayout);
            app.CryptographictextArea.Editable = 'off';
            app.CryptographictextLabel = uilabel(app.GridLayout);
            app.CryptographictextLabel.Text = 'Cryptographic text';
            app.KeyField = uieditfield(app.GridLayout, 'text');
            app.KeyLabel = uilabel(app.GridLayout);
            app.KeyLabel.Text = 'Key';
            % Show the figure after all components are created
            app.SimpleVigenereUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VigenereGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimpleVigenereUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimpleVigenereUIFigure)
        end
    end
end